classdef DiceGameProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        RollAllButton            matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        CheckBox                 matlab.ui.control.CheckBox
        CheckBox_2               matlab.ui.control.CheckBox
        CheckBox_3               matlab.ui.control.CheckBox
        CheckBox_5               matlab.ui.control.CheckBox
        CheckBox_4               matlab.ui.control.CheckBox
        ClickthediceyouwishtorerollLabel  matlab.ui.control.Label
        RollSelectedButton       matlab.ui.control.Button
        EndTurnButton            matlab.ui.control.Button
        EnterNameEditFieldLabel  matlab.ui.control.Label
        EnterNameEditField       matlab.ui.control.EditField
        KismetLabel              matlab.ui.control.Label
        Image_2                  matlab.ui.control.Image
        Image_3                  matlab.ui.control.Image
        Image_4                  matlab.ui.control.Image
        Image_5                  matlab.ui.control.Image
        CountLabel               matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        scorecard1 = scorecard; % Description
        roller;
        imageFiles;
        
    end
    
    properties(Dependent, Access = private)
        selectedDice;
    end
    
    methods (Access = private)      
        function turnEnd(app)
            app.Label.Text = num2str(app.roller.rollCount);
            if (app.roller.rollCount == 3)
                app.RollAllButton.Enable = false;
                app.RollSelectedButton.Enable = false;
                app.EndTurnButton.BackgroundColor = [0.0, 1.0, 0.0];
            end
        end
        
        function diceImages(app)
            if(app.roller.rollCount == 0)
                app.Image.ImageSource = app.imageFiles(1);
                app.Image_2.ImageSource = app.imageFiles(1);
                app.Image_3.ImageSource = app.imageFiles(1);
                app.Image_4.ImageSource = app.imageFiles(1);
                app.Image_5.ImageSource = app.imageFiles(1);
            else
                vals = app.roller.diceValues;
                app.Image.ImageSource = app.imageFiles(vals(1)+1);
                app.Image_2.ImageSource = app.imageFiles(vals(2)+1);
                app.Image_3.ImageSource = app.imageFiles(vals(3)+1);
                app.Image_4.ImageSource = app.imageFiles(vals(4)+1);
                app.Image_5.ImageSource = app.imageFiles(vals(5)+1);
            end
        end
    end
    
    methods
        function selected = get.selectedDice(app)
            vals = [app.CheckBox.Value, app.CheckBox_2.Value, app.CheckBox_3.Value, app.CheckBox_4.Value, app.CheckBox_5.Value];
            selected = 1:5;
            selected = selected(vals);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.scorecard1 = scorecard();
            app.roller = diceRoller(5,3);
            app.imageFiles = ["unrolled.png", "ones.png","twos.png",...
            "threes.png", "fours.png", "fives.png", "sixes.png"];
            app.diceImages();
            app.CheckBoxValueChanged();
        end

        % Value changed function: EnterNameEditField
        function EnterNameEditFieldValueChanged(app, event)
            value = app.EnterNameEditField.Value;
            app.scorecard1.name = value;
        end

        % Callback function
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Callback function
        function EnterNameEditFieldValueChanging(app, event)
            
        end

        % Button pushed function: RollAllButton
        function RollAllButtonPushed(app, event)
            app.roller.rollAll();
            app.turnEnd();
            app.diceImages();
            app.CheckBox.Value = false;
        end

        % Button pushed function: RollSelectedButton
        function RollSelectedButtonPushed(app, event)
            app.roller.rollSelected(app.selectedDice);
            app.turnEnd();
            app.diceImages();
            app.CheckBox.Value = false;
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            app.UITable.Data = array2table(app.scorecard1.fullCard);
            score = scorer(app.roller.diceValues, app.roller.diceColors);
            [bestScore, idx] = score.getBestScore();
            app.scorecard1 = app.scorecard1.inputScores(bestScore, idx);
            app.roller = diceRoller(5,3);
            app.diceImages();
            app.RollAllButton.Enable = true;
            app.RollSelectedButton.Enable = true;
            app.EndTurnButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.Label.Text = num2str(app.roller.rollCount);
            app.CheckBox.Value = false;
            app.CheckBox_2.Value = false;
            app.CheckBox_3.Value = false;
            app.CheckBox_4.Value = false;
            app.CheckBox_5.Value = false;
            app.CheckBoxValueChanged();
        end

        % Value changed function: CheckBox, CheckBox_2, CheckBox_3, 
        % CheckBox_4, CheckBox_5
        function CheckBoxValueChanged(app, event)
            if(isempty(app.selectedDice) || app.roller.rollCount == 0)
                app.RollSelectedButton.Enable = false;
            else
                app.RollSelectedButton.Enable = true;
            end
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'Aces'; 'Deuces'; 'Treys'; 'Fours'; 'Fives'; 'Sixes'; '2 Pair Same Color'; '3 of a Kind'; 'Straight'; 'Flush'; 'Full House'; 'Full House Same Color'; '4 of a Kind'; 'Yarborough'; 'Kismet'; 'Bonus Score'; 'Total Score'};
            app.UITable.RowName = {'Player 1'};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.RollAllButton = uibutton(app.UIFigure, 'push');
            app.RollAllButton.ButtonPushedFcn = createCallbackFcn(app, @RollAllButtonPushed, true);
            app.RollAllButton.Text = 'Roll All';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = {''; ''};
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_2.Text = {''; ''};
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_3.Text = {''; ''};
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_5.Text = {''; ''};
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox_4.Text = {''; ''};
            app.ClickthediceyouwishtorerollLabel = uilabel(app.UIFigure);
            app.ClickthediceyouwishtorerollLabel.Text = 'Click the dice you wish to reroll.';
            app.RollSelectedButton = uibutton(app.UIFigure, 'push');
            app.RollSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RollSelectedButtonPushed, true);
            app.RollSelectedButton.Text = 'Roll Selected';
            app.EndTurnButton = uibutton(app.UIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.EnterNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterNameEditFieldLabel.Text = 'Enter Name';
            app.EnterNameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterNameEditFieldValueChanged, true);
            app.EnterNameEditField.Value = 'Player 1';
            app.KismetLabel = uilabel(app.UIFigure);
            app.KismetLabel.Text = 'Kismet';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_5 = uiimage(app.UIFigure);
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'Count: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'/3'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiceGameProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end